classdef Project_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        On_Off_Switch       matlab.ui.control.Switch
        On_Off_SwitchLabel  matlab.ui.control.Label
        SimulateButton      matlab.ui.control.Button
        L1EditField         matlab.ui.control.NumericEditField
        L1EditFieldLabel    matlab.ui.control.Label
        L2EditField         matlab.ui.control.NumericEditField
        L2EditFieldLabel    matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            hold(app.UIAxes,"off")
            L1 = app.L1EditField.Value; % Length of first arm
            L2 = app.L2EditField.Value; % Length of second arm

            theta1 = linspace(-85, 85, 1000);%angle that arm1 makes with x axis
            phi0 = linspace(0 , -50, 500);%angle that arm2 makes with arm1 axis
            phi1 = linspace(-50,0,500);
            phi=[phi0 , phi1];


            x1 = L1 * cosd(theta1); % x component of first arm
            y1 = L1 * sind(theta1); % y component of first arm
            x2 = x1 + L2 * cosd(theta1 + phi);  % x component of second arm
            y2 = y1 + L2 * sind(theta1 + phi); % y component of second arm
            
            % Plot initial configuration
            p1 = plot(app.UIAxes,[0, x1(1)], [0, y1(1)],'LineWidth',8);
            hold(app.UIAxes,"on")
            p2 = plot(app.UIAxes,[x1(1), x2(1)], [y1(1), y2(1)],'LineWidth',8);
            p3 = plot(app.UIAxes,x2(1), y2(1), 'Marker', 'o', 'Color', 'g', 'MarkerSize', 8);

            %lmiting axis limits to see the simualtion smoothly

            xlim(app.UIAxes,[-L1 * 3, L1 * 3]);
            ylim(app.UIAxes,[-L1 * 3, L1 * 3]);
            
            
            while(1)
                i=2;
                while (i <numel(theta1))
                    if (app.On_Off_Switch.Value=="On")
                    delete(p1);  % Delete the previous plot for the first arm
                    delete(p2);  % Delete the previous plot for the second arm
                    delete(p3);  % Delete the previous marker


                    % Plot the first arm
                    p1 = plot(app.UIAxes,[0, x1(i)], [0, y1(i)], 'g' , 'LineWidth',8);

                    % Plot the second arm
                    p2 = plot(app.UIAxes,[x1(i), x2(i)], [y1(i), y2(i)], 'r',  'LineWidth',8 );

                    % Plot the marker
                    p3 = plot(app.UIAxes,x2(i), y2(i), 'Marker', '.', 'Color', 'k', 'MarkerSize', 38);

                    drawnow
                    pause(0.01);
                    i=i+20;
                    else
                        pause(1.5);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Arm Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.Limits = [0 20];
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.Limits = [0 15];
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.On_Off_SwitchLabel = uilabel(app.UIFigure);
            app.On_Off_SwitchLabel.Text = 'On_Off_Switch';
            app.On_Off_Switch = uiswitch(app.UIFigure, 'slider');
            app.On_Off_Switch.Value = 'On';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Mohamed Gaafar'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end